classdef BEV_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        DropDown_2                     matlab.ui.control.DropDown
        DropDown                       matlab.ui.control.DropDown
        SavefigureButton               matlab.ui.control.Button
        SavedataButton                 matlab.ui.control.Button
        RunModelButton                 matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        DrivingdistancekmEditField     matlab.ui.control.NumericEditField
        DrivingdistancekmEditFieldLabel  matlab.ui.control.Label
        ElectricenergyxonskWh100kmEditField  matlab.ui.control.NumericEditField
        ElectricenergyxonskWh100kmEditFieldLabel  matlab.ui.control.Label
        BEVSimulatorLabel              matlab.ui.control.Label
        InputPanel                     matlab.ui.container.Panel
        CycletimeCheckBox              matlab.ui.control.CheckBox
        FrontalAream2EditField         matlab.ui.control.NumericEditField
        FrontalAream2EditFieldLabel    matlab.ui.control.Label
        AeroDragCoefficentEditField    matlab.ui.control.NumericEditField
        AeroDragCoefficentEditFieldLabel  matlab.ui.control.Label
        RollingResistanceCoefficentEditField  matlab.ui.control.NumericEditField
        RollingResistanceCoefficentEditFieldLabel  matlab.ui.control.Label
        SimulationTimesEditField       matlab.ui.control.NumericEditField
        SimulationTimesEditFieldLabel  matlab.ui.control.Label
        VehicleWeightkgEditField       matlab.ui.control.NumericEditField
        VehicleWeightkgEditFieldLabel  matlab.ui.control.Label
        DrivingCycleDropDown           matlab.ui.control.DropDown
        DrivingCycleDropDownLabel      matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 向基础工作区传入仿真初始化参数
            assignin('base','m',1500);
            assignin('base','f',0.008);
            assignin('base','basicsimtime',2500);
            assignin('base','Cd',0.34);
            assignin('base','A',2.2);
            % 仿真类型参数
            assignin('base','type',1);
            % 是否进行仿真标签
            assignin('base','simflag',0);
            % 是否为周期性仿真标签
            assignin('base','simtimeflag',0);
            % 图表标签
            assignin('base','fg1',1);
            assignin('base','fg2',1);
            % 运行文件，导入工况数据
            run("final.m");
            % 将工况数据传入基础工作区
            assignin('base','CAFE',CAFE);
            assignin('base','CNBUS',CNBUS);
            assignin('base','cyc_mph',cyc_mph);
            assignin('base','EUDC',EUDC);
            assignin('base','FTP_75',FTP_75);
            assignin('base','HWFET',HWFET);
            assignin('base','JC10',JC10);
            assignin('base','JC10_15',JC10_15);
            assignin('base','JC15',JC15);
            assignin('base','NEDC',NEDC);
            assignin('base','R_cha_SOC',R_cha_SOC);
            assignin('base','R_dch_SOC',R_dch_SOC);
            assignin('base','UDC',UDC);
            assignin('base','Vocv_cha_SOC',Vocv_cha_SOC);
            assignin('base','timedata',timedata);
        end

        % Value changed function: VehicleWeightkgEditField
        function VehicleWeightkgEditFieldValueChanged(app, event)
            % 获取车辆重量
            m = app.VehicleWeightkgEditField.Value;
            assignin('base','m',m);
        end

        % Value changed function: RollingResistanceCoefficentEditField
        function RollingResistanceCoefficentEditFieldValueChanged(app, event)
            % 获取车辆滚动阻力系数
            f = app.RollingResistanceCoefficentEditField.Value;
            assignin('base','f',f);
        end

        % Value changed function: SimulationTimesEditField
        function SimulationTimesEditFieldValueChanged(app, event)
            % 获取仿真时间
            basicsimtime = app.SimulationTimesEditField.Value;
            assignin('base','basicsimtime',basicsimtime);
        end

        % Value changed function: AeroDragCoefficentEditField
        function AeroDragCoefficentEditFieldValueChanged(app, event)
            % 获取风阻系数
            Cd = app.AeroDragCoefficentEditField.Value;
            assignin('base','Cd',Cd);
        end

        % Value changed function: FrontalAream2EditField
        function FrontalAream2EditFieldValueChanged(app, event)
            % 获取车辆迎风面积
            A = app.FrontalAream2EditField.Value;
            assignin('base','A',A);
        end

        % Value changed function: DrivingCycleDropDown
        function DrivingCycleDropDownValueChanged(app, event)
            % 获取仿真类型
            type = app.DrivingCycleDropDown.ValueIndex;
            % 将仿真类型传入基础工作区
            assignin('base','type',type);
            % 如果选择周期性仿真，改变对应周期时间
            if evalin('base','simtimeflag')
                timedata = evalin("base",'timedata');
                app.SimulationTimesEditField.Value=timedata(type);
            end
        end

        % Value changed function: CycletimeCheckBox
        function CycletimeCheckBoxValueChanged(app, event)
            % 获取是否按周期仿真
            value = app.CycletimeCheckBox.Value;
            % 若按周期仿真，则关闭Simulation Time (s)控件，并显示仿真周期时间
            % 若关闭按周期仿真，则打开Simulation Time (s)控件，并退回典型仿真时间
            if value==1
                simtimeflag = 1;
                app.SimulationTimesEditField.Enable = 'off';
                timedata = evalin("base",'timedata');
                app.SimulationTimesEditField.Value = timedata(evalin("base",'type'));
            else
                simtimeflag = 0;
                app.SimulationTimesEditField.Enable = 'on';
                app.SimulationTimesEditField.Value = 2500;
            end
            % 将是否按周期仿真传入基础工作区
            assignin('base','simtimeflag',simtimeflag);
        end

        % Value changed function: ElectricenergyxonskWh100kmEditField
        function ElectricenergyxonskWh100kmEditFieldValueChanged(app, event)
            %app.EditField.Value=num2str(changingValue);
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            % 运行仿真程序
            simOut = sim('sim_final.slx');
            assignin('base','simflag',1);
            assignin('base','simOut',simOut);
            % 绘制图像1
            if evalin("base",'fg1')==1
                x=simOut.tout;
                y=simOut.v.Data;
                plot(app.UIAxes,x,y);
                xlabel(app.UIAxes,'Time (s)');
                ylabel(app.UIAxes,'Speed (m/s)');
                title(app.UIAxes,'Vehicle Speed');
            elseif evalin("base",'fg1')==2
                x=simOut.tout;
                xlabel(app.UIAxes,'Time (s)');
                y=simOut.SOC.Data.*100;
                ylabel(app.UIAxes,'SOC (%)');
                plot(app.UIAxes,x,y);
                title(app.UIAxes,'SOC of Battery');
            elseif evalin("base",'fg1')==3
                x=simOut.tout;
                xlabel(app.UIAxes,'Time (s)');
                y=simOut.Pbat.Data./1000;
                ylabel(app.UIAxes,'Pbat (kW)');
                plot(app.UIAxes,x,y);
                title(app.UIAxes,'Pbat');
            elseif evalin("base",'fg1')==4
                x=simOut.tout;
                xlabel(app.UIAxes,'Time (s)');
                y=simOut.Torque.Data;
                ylabel(app.UIAxes,'Torque (N.m)');
                plot(app.UIAxes,x,y);
                title(app.UIAxes,'Torque');
            elseif evalin("base",'fg1')==5
                x=simOut.tout;
                xlabel(app.UIAxes,'Time (s)');
                y=simOut.distance.Data./1000;
                ylabel(app.UIAxes,'L (km)');
                plot(app.UIAxes,x,y);
                title(app.UIAxes,'L');
            end
            % 绘制图像2
            if evalin("base",'fg2')==1
                x=simOut.tout;
                y=simOut.v.Data;
                plot(app.UIAxes_2,x,y);
                xlabel(app.UIAxes_2,'Time (s)');
                ylabel(app.UIAxes_2,'Speed (m/s)');
                title(app.UIAxes_2,'Vehicle Speed');
            elseif evalin("base",'fg2')==2
                x=simOut.tout;
                xlabel(app.UIAxes_2,'Time (s)');
                y=simOut.SOC.Data.*100;
                ylabel(app.UIAxes_2,'SOC (%)');
                plot(app.UIAxes_2,x,y);
                title(app.UIAxes_2,'SOC of Battery');
            elseif evalin("base",'fg2')==3
                x=simOut.tout;
                xlabel(app.UIAxes_2,'Time (s)');
                y=simOut.Pbat.Data./1000;
                ylabel(app.UIAxes_2,'Pbat (kW)');
                plot(app.UIAxes_2,x,y);
                title(app.UIAxes_2,'Pbat');
            elseif evalin("base",'fg2')==4
                x=simOut.tout;
                xlabel(app.UIAxes_2,'Time (s)');
                y=simOut.Torque.Data;
                ylabel(app.UIAxes_2,'Torque (N.m)');
                plot(app.UIAxes_2,x,y);
                title(app.UIAxes_2,'Torque');
            elseif evalin("base",'fg2')==5
                x=simOut.tout;
                xlabel(app.UIAxes_2,'Time (s)');
                y=simOut.distance.Data./1000;
                ylabel(app.UIAxes_2,'L (km)');
                plot(app.UIAxes_2,x,y);
                title(app.UIAxes_2,'L');
            end
            % 显示仿真数据
            app.ElectricenergyxonskWh100kmEditField.Value=simOut.xons.Data(end);
            app.DrivingdistancekmEditField.Value=simOut.distance.Data(end)/1000;
            assignin('base','xons',simOut.xons.Data(end));
            assignin('base','distance',simOut.distance.Data(end)/1000);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % 获取下拉框值
            value = app.DropDown.Value;
            assignin("base",'fg1',value);
            % 如果已进行仿真，则绘制图像1
            if evalin("base",'simflag')
                simOut = evalin("base",'simOut');
                if evalin("base",'fg1')==1
                    x=simOut.tout;
                    y=simOut.v.Data;
                    plot(app.UIAxes,x,y);
                    xlabel(app.UIAxes,'Time (s)');
                    ylabel(app.UIAxes,'Speed (m/s)');
                    title(app.UIAxes,'Vehicle Speed');
                elseif evalin("base",'fg1')==2
                    x=simOut.tout;
                    xlabel(app.UIAxes,'Time (s)');
                    y=simOut.SOC.Data.*100;
                    ylabel(app.UIAxes,'SOC (%)');
                    plot(app.UIAxes,x,y);
                    title(app.UIAxes,'SOC of Battery');
                elseif evalin("base",'fg1')==3
                    x=simOut.tout;
                    xlabel(app.UIAxes,'Time (s)');
                    y=simOut.Pbat.Data./1000;
                    ylabel(app.UIAxes,'Pbat (kW)');
                    plot(app.UIAxes,x,y);
                    title(app.UIAxes,'Pbat');
                elseif evalin("base",'fg1')==4
                    x=simOut.tout;
                    xlabel(app.UIAxes,'Time (s)');
                    y=simOut.Torque.Data;
                    ylabel(app.UIAxes,'Torque (N.m)');
                    plot(app.UIAxes,x,y);
                    title(app.UIAxes,'Torque');
                elseif evalin("base",'fg1')==5
                    x=simOut.tout;
                    xlabel(app.UIAxes,'Time (s)');
                    y=simOut.distance.Data./1000;
                    ylabel(app.UIAxes,'L (km)');
                    plot(app.UIAxes,x,y);
                    title(app.UIAxes,'L');
                end
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            % 获取下拉框值
            value = app.DropDown_2.Value;
            assignin("base",'fg2',value);
            % 如果已进行仿真，则绘制图像2
            if evalin("base",'simflag')
                simOut = evalin("base",'simOut');
                if evalin("base",'fg2')==1
                    x=simOut.tout;
                    y=simOut.v.Data;
                    plot(app.UIAxes_2,x,y);
                    xlabel(app.UIAxes_2,'Time (s)');
                    ylabel(app.UIAxes_2,'Speed (m/s)');
                    title(app.UIAxes_2,'Vehicle Speed');
                elseif evalin("base",'fg2')==2
                    x=simOut.tout;
                    xlabel(app.UIAxes_2,'Time (s)');
                    y=simOut.SOC.Data.*100;
                    ylabel(app.UIAxes_2,'SOC (%)');
                    plot(app.UIAxes_2,x,y);
                    title(app.UIAxes_2,'SOC of Battery');
                elseif evalin("base",'fg2')==3
                    x=simOut.tout;
                    xlabel(app.UIAxes_2,'Time (s)');
                    y=simOut.Pbat.Data./1000;
                    ylabel(app.UIAxes_2,'Pbat (kW)');
                    plot(app.UIAxes_2,x,y);
                    title(app.UIAxes_2,'Pbat');
                elseif evalin("base",'fg2')==4
                    x=simOut.tout;
                    xlabel(app.UIAxes_2,'Time (s)');
                    y=simOut.Torque.Data;
                    ylabel(app.UIAxes_2,'Torque (N.m)');
                    plot(app.UIAxes_2,x,y);
                    title(app.UIAxes_2,'Torque');
                elseif evalin("base",'fg2')==5
                    x=simOut.tout;
                    xlabel(app.UIAxes_2,'Time (s)');
                    y=simOut.distance.Data./1000;
                    ylabel(app.UIAxes_2,'L (km)');
                    plot(app.UIAxes_2,x,y);
                    title(app.UIAxes_2,'L');
                end
            end
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if evalin("base",'simflag')
                % 生成保存数据
                data = [evalin("base",'xons') evalin("base",'distance')]';
                % 弹出文件选择对话框，让用户选择保存位置和文件名
                [file, path] = uiputfile('*.mat', 'Save Data');
                % 如果没有取消选择
                if file ~= 0
                    % 构建完整的文件路径并保存为MAT文件
                    save(fullfile(path, file), 'data');
                end
            end
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            if evalin("base",'simflag')
                himage = app.UIAxes.Parent;
                % 弹出文件选择对话框，让用户选择保存位置和文件名
                [file, path] = uiputfile({'*.fig';'*.jpg';'*.png'; '*.*'}, 'Save Image');
                % 如果没有取消选择
                if file~=0
                    if  contains(file,'.fig')
                        % 构建完整的文件路径并保存图像
                        savefig(himage,fullfile(path, file));
                    else
                        % 构建完整的文件路径并保存图像
                        exportgraphics(himage,fullfile(path, file));
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DrivingCycleDropDownLabel = uilabel(app.InputPanel);
            app.DrivingCycleDropDownLabel.Text = 'Driving Cycle';
            app.DrivingCycleDropDown = uidropdown(app.InputPanel);
            app.DrivingCycleDropDown.Items = {'NEDC', 'CAFÉ', 'CNBUS', 'EUDC', 'FTP_75', 'HWFET', 'JC10', 'JC10_15', 'JC15', 'UDC'};
            app.DrivingCycleDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.DrivingCycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DrivingCycleDropDownValueChanged, true);
            app.DrivingCycleDropDown.Value = 1;
            app.VehicleWeightkgEditFieldLabel = uilabel(app.InputPanel);
            app.VehicleWeightkgEditFieldLabel.Text = 'Vehicle Weight (kg)';
            app.VehicleWeightkgEditField = uieditfield(app.InputPanel, 'numeric');
            app.VehicleWeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @VehicleWeightkgEditFieldValueChanged, true);
            app.VehicleWeightkgEditField.Value = 1500;
            app.SimulationTimesEditFieldLabel = uilabel(app.InputPanel);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time(s)';
            app.SimulationTimesEditField = uieditfield(app.InputPanel, 'numeric');
            app.SimulationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesEditFieldValueChanged, true);
            app.SimulationTimesEditField.Value = 2500;
            app.RollingResistanceCoefficentEditFieldLabel = uilabel(app.InputPanel);
            app.RollingResistanceCoefficentEditFieldLabel.Text = 'Rolling Resistance Coefficent';
            app.RollingResistanceCoefficentEditField = uieditfield(app.InputPanel, 'numeric');
            app.RollingResistanceCoefficentEditField.ValueChangedFcn = createCallbackFcn(app, @RollingResistanceCoefficentEditFieldValueChanged, true);
            app.RollingResistanceCoefficentEditField.Value = 0.008;
            app.AeroDragCoefficentEditFieldLabel = uilabel(app.InputPanel);
            app.AeroDragCoefficentEditFieldLabel.Text = 'Aero Drag Coefficent';
            app.AeroDragCoefficentEditField = uieditfield(app.InputPanel, 'numeric');
            app.AeroDragCoefficentEditField.ValueChangedFcn = createCallbackFcn(app, @AeroDragCoefficentEditFieldValueChanged, true);
            app.AeroDragCoefficentEditField.Value = 0.34;
            app.FrontalAream2EditFieldLabel = uilabel(app.InputPanel);
            app.FrontalAream2EditFieldLabel.Text = 'Frontal Area (m^2)';
            app.FrontalAream2EditField = uieditfield(app.InputPanel, 'numeric');
            app.FrontalAream2EditField.ValueChangedFcn = createCallbackFcn(app, @FrontalAream2EditFieldValueChanged, true);
            app.FrontalAream2EditField.Value = 2.2;
            app.CycletimeCheckBox = uicheckbox(app.InputPanel);
            app.CycletimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CycletimeCheckBoxValueChanged, true);
            app.CycletimeCheckBox.Text = 'Cycle time';
            app.BEVSimulatorLabel = uilabel(app.UIFigure);
            app.BEVSimulatorLabel.Text = 'BEV Simulator';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.ElectricenergyxonskWh100kmEditFieldLabel = uilabel(app.OutputPanel);
            app.ElectricenergyxonskWh100kmEditFieldLabel.Text = 'Electric energy xons. (kWh/100km)';
            app.ElectricenergyxonskWh100kmEditField = uieditfield(app.OutputPanel, 'numeric');
            app.ElectricenergyxonskWh100kmEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricenergyxonskWh100kmEditFieldValueChanged, true);
            app.ElectricenergyxonskWh100kmEditField.Editable = 'off';
            app.DrivingdistancekmEditFieldLabel = uilabel(app.OutputPanel);
            app.DrivingdistancekmEditFieldLabel.Text = 'Driving distance (km)';
            app.DrivingdistancekmEditField = uieditfield(app.OutputPanel, 'numeric');
            app.DrivingdistancekmEditField.Editable = 'off';
            app.RunModelButton = uibutton(app.UIFigure, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.SavefigureButton = uibutton(app.UIFigure, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Speed_t', 'SOC_t', 'Pbat_t', 'Torque_t', 'L_t'};
            app.DropDown.ItemsData = [1 2 3 4 5];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 1;
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Speed_t', 'SOC_t', 'Pbat_t', 'Torque_t', 'L_t'};
            app.DropDown_2.ItemsData = [1 2 3 4 5];
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '终版校徽.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEV_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end